classdef proyecto_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        TablaEbN0Panel    matlab.ui.container.Panel
        TablaDelta        matlab.ui.control.Table
        TablaButton       matlab.ui.control.Button
        MetricasPanel     matlab.ui.container.Panel
        MetricasTextArea  matlab.ui.control.TextArea
        ControlesPanel    matlab.ui.container.Panel
        SimularButton     matlab.ui.control.Button
        NEditField        matlab.ui.control.NumericEditField
        NsimbolosLabel    matlab.ui.control.Label
        EbNoEditField     matlab.ui.control.NumericEditField
        EbN0dBLabel       matlab.ui.control.Label
        BetaEditField     matlab.ui.control.NumericEditField
        BetaLabel         matlab.ui.control.Label
        MEditField        matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        MLabel            matlab.ui.control.Label
        TipoDropDown      matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        TipoLabel         matlab.ui.control.Label
        UIAxes5           matlab.ui.control.UIAxes
        UIAxes4           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
%% === 1. Leer parámetros de la GUI ===
            tipo    = app.TipoDropDown.Value;
            M       = round(app.MEditField.Value);
            beta    = app.BetaEditField.Value;
            EbNo_dB = app.EbNoEditField.Value;
            N       = round(app.NEditField.Value);
            
            %% === 2. VALIDACIÓN (RNF1) ===
            if beta < 0 || beta > 1
                uialert(app.UIFigure, ...
                    'Beta (roll-off) debe estar entre 0 y 1.', 'Error');
                return;
            end
            
            if M < 2 || mod(log2(M), 1) ~= 0
                uialert(app.UIFigure, ...
                    'M debe ser una potencia de 2 (2, 4, 8, 16, ...).', 'Error');
                return;
            end
            
            if strcmp(tipo, 'QAM') && mod(log2(M), 2) ~= 0
                uialert(app.UIFigure, ...
                    'Para QAM, M debe ser potencia de 4 (4, 16, 64, 256...).', 'Error');
                return;
            end
            % Validación: Advertencia para QAM > 16 (pero deja simular)
            if M > 16 && strcmp(tipo, 'QAM')
                uialert(app.UIFigure, ...
                    'Advertencia: Para M > 16 (ej. 64-QAM o 256-QAM), la codificación Gray 2D ya no es 100% óptima como en la teoría estándar. El sistema simulará correctamente, pero la BER medida podría ser ligeramente superior a la teórica.', ...
                    'Advertencia Teórica', 'Icon', 'warning');
                % Nota: NO ponemos 'return' aquí, por lo que la simulación continúa.
            end
            
            if N < 100 || N > 1e6
                uialert(app.UIFigure, ...
                    'N debe estar entre 100 y 1,000,000.', 'Error');
                return;
            end
            
            if EbNo_dB < 0 || EbNo_dB > 40
                uialert(app.UIFigure, ...
                    'Eb/N0 debe estar entre 0 y 40 dB.', 'Error');
                return;
            end
            
            %% === 3. Parámetros fijos ===
            span = 6;
            sps  = 8;
            
            %% === 4. Ejecutar el sistema ===
            % -- Mapeo --
            switch upper(tipo)
                case 'PAM', [simbolos, bits_tx, k] = mapeo_pam(M, N);
                case 'QAM', [simbolos, bits_tx, k] = mapeo_qam(M, N);
                case 'PSK', [simbolos, bits_tx, k] = mapeo_psk(M, N);
            end
            
            % -- Filtro y transmisión --
            h       = filtro_srrc(beta, span, sps);
            retardo = span * sps;
            simb_up = mi_upsample(simbolos, sps);
            sen_tx  = conv(simb_up, h, 'full');
            
            % -- Canal AWGN --
            sen_rx = canal_awgn(sen_tx, EbNo_dB, k);
            
            % -- Filtro Rx y muestreo --
            sen_fil  = conv(sen_rx, h, 'full');
            muestras = sen_fil(retardo+1 : sps : retardo + N*sps);
            
            % -- Decisión --
            C = constelacion(M, tipo);
            [bits_rx, ~] = decisor(muestras, C, k);
            
            % -- BER puntual --
            BER = sum(bits_tx ~= bits_rx) / length(bits_tx);
            
            %% === 5. Gráfica 1: Constelación Rx ===
            cla(app.UIAxes);
            plot(app.UIAxes, real(muestras), imag(muestras), ...
                 'bo', 'MarkerSize', 3);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, real(C), imag(C), ...
                 'rx', 'MarkerSize', 10, 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            title(app.UIAxes, sprintf('Constelación Rx %d-%s', M, tipo));
            xlabel(app.UIAxes, 'I');
            ylabel(app.UIAxes, 'Q');
            
            %% === 6. Gráfica 2: Diagrama del ojo ===
            sen_fil_ojo = sen_fil(retardo+1 : end-retardo);
            L = 2*sps;
            num_seg = min(floor((length(sen_fil_ojo) - L)/sps), 400);
            segmentos = zeros(num_seg, L);
            for i = 1:num_seg
                segmentos(i,:) = sen_fil_ojo((i-1)*sps+1 : (i-1)*sps+L).';
            end
            
            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');
            t_ojo = (0:L-1)/sps;
            for i = 1:num_seg
                plot(app.UIAxes2, t_ojo, real(segmentos(i,:)), ...
                     'Color', [0.6 0.6 0.9]);
            end
            xline(app.UIAxes2, 1, 'r--', 'LineWidth', 1.5);
            hold(app.UIAxes2, 'off');
            grid(app.UIAxes2, 'on');
            title(app.UIAxes2, sprintf('Diagrama del ojo (%.0f dB)', EbNo_dB));
            xlabel(app.UIAxes2, 'Tiempo (símbolos)');
            ylabel(app.UIAxes2, 'Amplitud');
            xlim(app.UIAxes2, [0 2]);
            
            %% === 7. Gráfica 3: Curva BER ===
            EbNo_range = 0:2:14;
            ber_curva  = zeros(size(EbNo_range));
            
            for i = 1:length(EbNo_range)
                sr = canal_awgn(sen_tx, EbNo_range(i), k);
                sf = conv(sr, h, 'full');
                mu = sf(retardo+1 : sps : retardo + N*sps);
                [br, ~] = decisor(mu, C, k);
                ber_curva(i) = sum(bits_tx ~= br) / length(bits_tx);
            end
            
            ber_plot = ber_curva;
            ber_plot(ber_plot == 0) = 1e-6;
            
            cla(app.UIAxes3);
            semilogy(app.UIAxes3, EbNo_range, ber_plot, ...
                     'b-o', 'LineWidth', 1.5, 'MarkerSize', 6);
            grid(app.UIAxes3, 'on');
            title(app.UIAxes3, 'Curva BER vs Eb/N0');
            xlabel(app.UIAxes3, 'Eb/N0 (dB)');
            ylabel(app.UIAxes3, 'BER');
            ylim(app.UIAxes3, [1e-6 1]);
            
            %% === 8. Gráfica 4: Espectro banda base ===
            Nfft = 2048;

            % -- Espectro en banda base --
            [PSD_bb, f_bb] = periodogram(sen_tx, hann(length(sen_tx)), ...
                Nfft, 1, 'centered');
            PSD_bb_dB = 10*log10(PSD_bb / max(PSD_bb));

            cla(app.UIAxes4);
            plot(app.UIAxes4, f_bb, PSD_bb_dB, 'b', 'LineWidth', 1.2);
            grid(app.UIAxes4, 'on');
            title(app.UIAxes4, 'Espectro banda base (Tx)');
            xlabel(app.UIAxes4, 'Frecuencia normalizada');
            ylabel(app.UIAxes4, 'PSD (dB)');
            xlim(app.UIAxes4, [-0.5 0.5]);
            ylim(app.UIAxes4, [-60 5]);

            %% === 8.2. Gráfica 5: Espectro pasa banda ===
            fc = 0.15;
            n_pb = (0:length(sen_tx)-1)';
            sen_pb = real(sen_tx) .* cos(2*pi*fc*n_pb);

            % -- Espectro en pasa banda --
            [PSD_pb, f_pb] = periodogram(sen_pb, hann(length(sen_pb)), ...
                Nfft, 1, 'centered');
            PSD_pb_dB = 10*log10(PSD_pb / max(PSD_pb));

            cla(app.UIAxes5);
            plot(app.UIAxes5, f_pb, PSD_pb_dB, 'r', 'LineWidth', 1.2);
            grid(app.UIAxes5, 'on');
            title(app.UIAxes5, sprintf('Espectro pasa banda (f_c = %.2f)', fc));
            xlabel(app.UIAxes5, 'Frecuencia normalizada');
            ylabel(app.UIAxes5, 'PSD (dB)');
            xlim(app.UIAxes5, [-0.5 0.5]);
            ylim(app.UIAxes5, [-60 5]);

            % Líneas de referencia en las portadoras
            hold(app.UIAxes5, 'on');
            xline(app.UIAxes5,  fc, 'k--', 'LineWidth', 1);
            xline(app.UIAxes5, -fc, 'k--', 'LineWidth', 1);
            hold(app.UIAxes5, 'off');
            %% === 9. Métricas (RF4) ===
            eta  = k / (1 + beta);
            Es   = mean(abs(simbolos).^2);
            BW   = 1 * (1 + beta);
            P_bb = mean(abs(sen_tx).^2);
            
            texto = sprintf([ ...
                'η  = %.3f bits/s/Hz\n' ...
                'Es = %.4f\n' ...
                'BW = %.3f\n' ...
                'P  = %.4f\n' ...
                'BER = %.2e'], eta, Es, BW, P_bb, BER);
            app.MetricasTextArea.Value = texto;
        
        end

        % Button pushed function: TablaButton
        function TablaButtonPushed(app, event)
            %% === 1. CÁLCULO TEÓRICO ===
            BER_obj = [1e-2, 1e-3, 1e-4, 1e-5, 1e-6, 1e-7];
            Qinv = @(p) sqrt(2)*erfcinv(2*p);

            EbNo_QAM = 10*log10( Qinv(BER_obj).^2 / 2 );
            EbNo_PSK = EbNo_QAM; 
            EbNo_PAM = 10*log10( Qinv(BER_obj/0.75).^2 / 0.8 );

            EbNo_tabla = [EbNo_PAM(:), EbNo_QAM(:), EbNo_PSK(:)];

            % CAMBIO AQUÍ: La tercera diferencia es 4PSK - 4PAM
            d1 = EbNo_tabla(:,1) - EbNo_tabla(:,2);   % 4PAM - 4QAM
            d2 = EbNo_tabla(:,3) - EbNo_tabla(:,2);   % 4PSK - 4QAM
            d3 = EbNo_tabla(:,3) - EbNo_tabla(:,1);   % 4PSK - 4PAM
            Delta = [d1, d2, d3];

            %% === 2. LLENAR LA TABLA PRINCIPAL EN LA GUI ===
            BER_labels = {'10⁻²', '10⁻³', '10⁻⁴', '10⁻⁵', '10⁻⁶', '10⁻⁷'}';
            data_tabla = cell(length(BER_labels), 4);
            for j = 1:length(BER_labels)
                data_tabla{j, 1} = BER_labels{j};
                data_tabla{j, 2} = sprintf('%+.2f', Delta(j, 1));
                data_tabla{j, 3} = sprintf('%+.2f', Delta(j, 2));
                data_tabla{j, 4} = sprintf('%+.2f', Delta(j, 3));
            end
            app.TablaDelta.Data = data_tabla;

            %% === 3. GRÁFICA DE BARRAS (FIGURA EXTERNA) ===
            figure('Name', 'ΔEb/N0 Teórico', 'Position', [100 100 800 400]);
            b = bar(Delta, 'grouped'); grid on;
            b(1).FaceColor = [0.2 0.4 0.8]; 
            b(2).FaceColor = [0.2 0.7 0.3]; 
            b(3).FaceColor = [0.8 0.2 0.2];
            set(gca, 'XTickLabel', {'10^{-2}','10^{-3}','10^{-4}','10^{-5}','10^{-6}','10^{-7}'});
            xlabel('BER'); ylabel('\Delta E_b/N_0 (dB)');
            title('Diferencias Teóricas de E_b/N_0');
            legend({'4PAM - 4QAM', '4PSK - 4QAM', '4PSK - 4PAM'}, 'Location', 'best');

            %% === 4. VALIDACIÓN POR SIMULACIÓN ===
            d = uiprogressdlg(app.UIFigure, 'Title', 'Validando...', ...
                'Message', 'Simulando para comparar teoría vs práctica...', 'Indeterminate', 'on');

            beta = app.BetaEditField.Value;
            span = 6; sps = 8; M_val = 4; N_val = 50000; 
            tipos = {'PAM', 'QAM', 'PSK'};
            N_validar = 3; 
            BER_sim = nan(N_validar, 3);

            h = filtro_srrc(beta, span, sps);
            retardo = span * sps;

            for t = 1:3
                switch tipos{t}
                    case 'PAM', [simb, bits_tx, k] = mapeo_pam(M_val, N_val);
                    case 'QAM', [simb, bits_tx, k] = mapeo_qam(M_val, N_val);
                    case 'PSK', [simb, bits_tx, k] = mapeo_psk(M_val, N_val);
                end
                C  = constelacion(M_val, tipos{t});
                tx = conv(mi_upsample(simb, sps), h, 'full');

                for j = 1:N_validar
                    rx       = canal_awgn(tx, EbNo_tabla(j,t), k);
                    filtrada = conv(rx, h, 'full');
                    muestras = filtrada(retardo+1 : sps : retardo + N_val*sps);
                    [bits_rx, ~] = decisor(muestras, C, k);
                    BER_sim(j,t) = sum(bits_tx ~= bits_rx) / length(bits_tx);
                end
            end
            close(d);

            %% === 5. MOSTRAR TABLA DE VALIDACIÓN EN VENTANA NUEVA ===
            fig_val = uifigure('Name', 'Validación Teoría vs Simulación', 'Position', [950 300 600 250]);

            data_val = cell(N_validar, 4);
            for j = 1:N_validar
                data_val{j, 1} = sprintf('%.0e', BER_obj(j));
                data_val{j, 2} = sprintf('%.2e', BER_sim(j,1));
                data_val{j, 3} = sprintf('%.2e', BER_sim(j,2));
                data_val{j, 4} = sprintf('%.2e', BER_sim(j,3));
            end

            uit = uitable(fig_val, 'Data', data_val, ...
                'ColumnName', {'BER Obj', '4-PAM sim', '4-QAM sim', '4-PSK sim'}, ...
                'RowName', {}, 'Position', [20 50 560 180]);

            uilabel(fig_val, 'Text', 'Comparación: La teoría predice la BER, la simulación confirma que es correcta.', ...
                'Position', [20 10 560 30], 'FontWeight', 'bold');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Constelacion Rx')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Diagrama del ojo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Curva BER')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Espectro')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, 'Espectro pasa banda')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.ControlesPanel = uipanel(app.GridLayout);
            app.ControlesPanel.Title = 'Controles';
            app.TipoLabel = uilabel(app.ControlesPanel);
            app.TipoLabel.Text = 'Tipo: ';
            app.Label = uilabel(app.ControlesPanel);
            app.Label.Text = '';
            app.TipoDropDown = uidropdown(app.ControlesPanel);
            app.TipoDropDown.Items = {'PAM', 'QAM', 'PSK'};
            app.TipoDropDown.Value = 'QAM';
            app.MLabel = uilabel(app.ControlesPanel);
            app.MLabel.Text = 'M: ';
            app.Label_2 = uilabel(app.ControlesPanel);
            app.Label_2.Text = '';
            app.MEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.MEditField.Value = 16;
            app.BetaLabel = uilabel(app.ControlesPanel);
            app.BetaLabel.Text = 'Beta: ';
            app.BetaEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.BetaEditField.Value = 0.35;
            app.EbN0dBLabel = uilabel(app.ControlesPanel);
            app.EbN0dBLabel.Text = 'Eb/N0 (dB): ';
            app.EbNoEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.EbNoEditField.Value = 10;
            app.NsimbolosLabel = uilabel(app.ControlesPanel);
            app.NsimbolosLabel.Text = 'N (simbolos):';
            app.NEditField = uieditfield(app.ControlesPanel, 'numeric');
            app.NEditField.Value = 5000;
            app.SimularButton = uibutton(app.ControlesPanel, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.MetricasPanel = uipanel(app.GridLayout);
            app.MetricasPanel.Title = 'Metricas';
            app.MetricasTextArea = uitextarea(app.MetricasPanel);
            app.MetricasTextArea.Editable = 'off';
            app.MetricasTextArea.Value = {'η = ---'};
            app.TablaEbN0Panel = uipanel(app.GridLayout);
            app.TablaEbN0Panel.Title = 'Tabla ΔEb/N0';
            app.TablaButton = uibutton(app.TablaEbN0Panel, 'push');
            app.TablaButton.ButtonPushedFcn = createCallbackFcn(app, @TablaButtonPushed, true);
            app.TablaButton.Text = 'Generar';
            app.TablaDelta = uitable(app.TablaEbN0Panel);
            app.TablaDelta.ColumnName = {'BER'; '4PAM-4QAM'; '4PSK-4QAM'; '4PSK-4PAM'};
            app.TablaDelta.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end